classdef SmartAudioCommunicationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmartAudioCommunicationSimulatorUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        SignalandPerformancePlotsPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PerformanceAxes                 matlab.ui.control.UIAxes
        ReconstructionAxes              matlab.ui.control.UIAxes
        ConstellationAxes               matlab.ui.control.UIAxes
        OriginalAxes                    matlab.ui.control.UIAxes
        PerformanceMetricsPanel         matlab.ui.container.Panel
        CapacityValueLabel              matlab.ui.control.Label
        ShannonCapacityLabel            matlab.ui.control.Label
        MSEValueLabel                   matlab.ui.control.Label
        ReconstructionMSELabel          matlab.ui.control.Label
        BERValueLabel                   matlab.ui.control.Label
        BERLabel                        matlab.ui.control.Label
        SimulationControlsPanel         matlab.ui.container.Panel
        ExportButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        RunSimulationButton             matlab.ui.control.Button
        SNRSlider                       matlab.ui.control.Slider
        ChannelSNRdBLabel               matlab.ui.control.Label
        ModulationDropDown              matlab.ui.control.DropDown
        ModulationschemeDropDownLabel   matlab.ui.control.Label
        SMARTAUDIOCOMMUNICATIONSIMULATORLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        LastResults = struct()
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
% Read the selected modulation and SNR
modulation = upper(string(app.ModulationDropDown.Value));
SNR_dB = app.SNRSlider.Value;

% Clear previous plots
cla(app.OriginalAxes);
cla(app.ConstellationAxes);
cla(app.ReconstructionAxes);
cla(app.PerformanceAxes);

%% 1. Generate the message signal

Fs = 8000;
fm = 300;
duration = 0.05;

t = 0:1/Fs:duration;
message = sin(2*pi*fm*t);

%% 2. Quantization and PCM encoding

n_bits = 8;
L = 2^n_bits;
xmin = -1;
xmax = 1;
delta = (xmax-xmin)/(L-1);

quantization_indices = round((message-xmin)/delta);
quantization_indices = max(0,min(L-1,quantization_indices));

quantized_message = xmin + quantization_indices*delta;

quantization_MSE = mean((message-quantized_message).^2);

binary_matrix = dec2bin(quantization_indices,n_bits)-'0';
pcm_bits = reshape(binary_matrix.',[],1);

%% 3. Digital modulation

sps = 8;

switch modulation
    case "BPSK"
        tx_symbols = 2*double(pcm_bits)-1;

    case "QPSK"
        bit_I = double(pcm_bits(1:2:end));
        bit_Q = double(pcm_bits(2:2:end));

        I = (1-2*bit_I)/sqrt(2);
        Q = (1-2*bit_Q)/sqrt(2);

        tx_symbols = I + 1j*Q;

    otherwise
        uialert(app.UIFigure, ...
            'Select BPSK or QPSK.', 'Invalid modulation');
        return;
end

tx_waveform = repelem(tx_symbols,sps);%#ok<NASGU>

%% 4. AWGN channel

symbol_power = mean(abs(tx_symbols).^2);
SNR_linear = 10^(SNR_dB/10);
noise_power = symbol_power/SNR_linear;

noise = sqrt(noise_power/2) .* ...
    (randn(size(tx_symbols)) + ...
     1j*randn(size(tx_symbols)));

rx_symbols = tx_symbols + noise;

%% 5. Demodulation

switch modulation
    case "BPSK"
        rx_bits = double(real(rx_symbols)>=0);

    case "QPSK"
        recovered_I = double(real(rx_symbols)<0);
        recovered_Q = double(imag(rx_symbols)<0);

        rx_bits = zeros(size(pcm_bits));
        rx_bits(1:2:end) = recovered_I;
        rx_bits(2:2:end) = recovered_Q;
end

rx_bits = rx_bits(:);

%% 6. BER calculation

BER = mean(double(pcm_bits(:)) ~= rx_bits);

%% 7. PCM decoding and reconstruction

rx_binary_matrix = reshape(rx_bits,n_bits,[]).';
weights = 2.^(n_bits-1:-1:0);

rx_indices = rx_binary_matrix*weights.';
rx_message = xmin + rx_indices.'*delta;

reconstruction_error = message-rx_message;
reconstruction_MSE = mean(reconstruction_error.^2);

%% 8. Performance sweep across SNR

SNR_range_dB = 0:2:20;
BER_sweep = zeros(size(SNR_range_dB));
MSE_sweep = zeros(size(SNR_range_dB));

for k = 1:length(SNR_range_dB)

    current_SNR = 10^(SNR_range_dB(k)/10);

    noise_power_k = symbol_power/current_SNR;

    noise_k = sqrt(noise_power_k/2) .* ...
        (randn(size(tx_symbols)) + ...
         1j*randn(size(tx_symbols)));

    r = tx_symbols + noise_k;

    switch modulation
        case "BPSK"
            bits_k = double(real(r)>=0);

        case "QPSK"
            bits_I_k = double(real(r)<0);
            bits_Q_k = double(imag(r)<0);

            bits_k = zeros(size(pcm_bits));
            bits_k(1:2:end) = bits_I_k;
            bits_k(2:2:end) = bits_Q_k;
    end

    bits_k = bits_k(:);

    BER_sweep(k) = ...
        mean(double(pcm_bits(:)) ~= bits_k);

    binary_k = reshape(bits_k,n_bits,[]).';
    indices_k = binary_k*weights.';

    reconstructed_k = xmin + indices_k.'*delta;

    MSE_sweep(k) = mean((message-reconstructed_k).^2);
end

%% 9. Shannon capacity

channel_bandwidth = Fs*n_bits;
capacity_kbps = channel_bandwidth* ...
    log2(1+SNR_linear)/1000;

%% 10. Update the Original Signal axes

plot(app.OriginalAxes,t*1000,message, ...
    'b-','LineWidth',1.4);
hold(app.OriginalAxes,'on');

stairs(app.OriginalAxes,t*1000,quantized_message, ...
    'r--','LineWidth',1.0);

grid(app.OriginalAxes,'on');
xlabel(app.OriginalAxes,'Time (ms)');
ylabel(app.OriginalAxes,'Amplitude');
title(app.OriginalAxes,'Original and Quantized Signal');

legend(app.OriginalAxes, ...
    {'Original','Quantized'},'Location','best');

hold(app.OriginalAxes,'off');

%% 11. Update the received constellation

plot(app.ConstellationAxes, ...
    real(rx_symbols),imag(rx_symbols), ...
    'b.','MarkerSize',8);

hold(app.ConstellationAxes,'on');

num_ref = min(500,length(tx_symbols));

plot(app.ConstellationAxes, ...
    real(tx_symbols(1:num_ref)), ...
    imag(tx_symbols(1:num_ref)), ...
    'ro','MarkerSize',4);

xline(app.ConstellationAxes,0,'k:');
yline(app.ConstellationAxes,0,'k:');

grid(app.ConstellationAxes,'on');
axis(app.ConstellationAxes,'equal');

xlabel(app.ConstellationAxes,'In-phase (I)');
ylabel(app.ConstellationAxes,'Quadrature (Q)');
title(app.ConstellationAxes, ...
    char(modulation+" Received Constellation"));

legend(app.ConstellationAxes, ...
    {'Received','Transmitted reference'}, ...
    'Location','best');

hold(app.ConstellationAxes,'off');

%% 12. Update the reconstructed signal

plot(app.ReconstructionAxes,t*1000,message, ...
    'b-','LineWidth',1.4);

hold(app.ReconstructionAxes,'on');

plot(app.ReconstructionAxes,t*1000,rx_message, ...
    'r--','LineWidth',1.2);

grid(app.ReconstructionAxes,'on');
xlabel(app.ReconstructionAxes,'Time (ms)');
ylabel(app.ReconstructionAxes,'Amplitude');

title(app.ReconstructionAxes, ...
    sprintf('Reconstruction at %.1f dB SNR',SNR_dB));

legend(app.ReconstructionAxes, ...
    {'Original','Reconstructed'},'Location','best');

hold(app.ReconstructionAxes,'off');

%% 13. Update BER and MSE performance plots

BER_display = max(BER_sweep, ...
    1/(2*length(pcm_bits)));

yyaxis(app.PerformanceAxes,'left');

semilogy(app.PerformanceAxes,SNR_range_dB, ...
    BER_display,'bo-','LineWidth',1.3);

ylabel(app.PerformanceAxes,'BER');

yyaxis(app.PerformanceAxes,'right');

plot(app.PerformanceAxes,SNR_range_dB, ...
    MSE_sweep,'rs-','LineWidth',1.3);

ylabel(app.PerformanceAxes,'Reconstruction MSE');

grid(app.PerformanceAxes,'on');
xlabel(app.PerformanceAxes,'SNR (dB)');
title(app.PerformanceAxes,'Performance vs SNR');

%% 14. Update the metric labels

app.BERValueLabel.Text = sprintf('%.6g',BER);

app.MSEValueLabel.Text = ...
    sprintf('%.6g',reconstruction_MSE);

app.CapacityValueLabel.Text = ...
    sprintf('%.2f kbps',capacity_kbps);

%% 15. Store the results for the Export button

app.LastResults = struct( ...
    'modulation',char(modulation), ...
    'SNR_dB',SNR_dB, ...
    'BER',BER, ...
    'reconstruction_MSE',reconstruction_MSE, ...
    'quantization_MSE',quantization_MSE, ...
    'SNR_range_dB',SNR_range_dB, ...
    'BER_sweep',BER_sweep, ...
    'MSE_sweep',MSE_sweep, ...
    'capacity_kbps',capacity_kbps, ...
    'Fs',Fs, ...
    'fm',fm, ...
    't',t, ...
    'message',message, ...
    'rx_message',rx_message, ...
    'pcm_bits',pcm_bits, ...
    'rx_bits',rx_bits);

fprintf('Simulation complete: %s, SNR = %.1f dB\n', ...
    char(modulation),SNR_dB);
fprintf('BER = %.8g\n',BER);
fprintf('Reconstruction MSE = %.8g\n',reconstruction_MSE);
fprintf('Shannon capacity = %.2f kbps\n',capacity_kbps);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
cla(app.OriginalAxes);
cla(app.ConstellationAxes);
cla(app.ReconstructionAxes);
cla(app.PerformanceAxes);

app.BERValueLabel.Text = 'Not run';
app.MSEValueLabel.Text = 'Not run';
app.CapacityValueLabel.Text = 'Not run';

app.LastResults = struct();

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
if isempty(fieldnames(app.LastResults))
    uialert(app.UIFigure, ...
        'Run a simulation before exporting results.', ...
        'No Results');
    return;
end

[file,path] = uiputfile( ...
    '*.csv','Export performance results', ...
    'performance_results.csv');

if isequal(file,0)
    return;
end

R = app.LastResults;

resultsTable = table( ...
    R.SNR_range_dB(:), ...
    R.BER_sweep(:), ...
    R.MSE_sweep(:), ...
    repmat(R.capacity_kbps, length(R.SNR_range_dB), 1), ...
    'VariableNames', ...
    {'SNR_dB','BER','Reconstruction_MSE', ...
     'Shannon_Capacity_kbps'});

writetable(resultsTable,fullfile(path,file));

% Save the full simulation data alongside the CSV
[~,baseName,~] = fileparts(file);

save(fullfile(path,[baseName,'.mat']),'-struct','R');

uialert(app.SmartAudioCommunicationAppUIFigure, ...
    'CSV and MAT results have been saved.', ...
    'Export Complete');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmartAudioCommunicationSimulatorUIFigure = uifigure('Visible', 'off');
            app.SmartAudioCommunicationSimulatorUIFigure.Name = 'Smart Audio Communication Simulator';
            app.GridLayout = uigridlayout(app.SmartAudioCommunicationSimulatorUIFigure);
            app.SMARTAUDIOCOMMUNICATIONSIMULATORLabel = uilabel(app.GridLayout);
            app.SMARTAUDIOCOMMUNICATIONSIMULATORLabel.Text = 'SMART AUDIO COMMUNICATION SIMULATOR';
            app.SimulationControlsPanel = uipanel(app.GridLayout);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.ModulationschemeDropDownLabel = uilabel(app.SimulationControlsPanel);
            app.ModulationschemeDropDownLabel.Text = 'Modulation scheme';
            app.ModulationDropDown = uidropdown(app.SimulationControlsPanel);
            app.ModulationDropDown.Items = {'BPSK', 'QPSK'};
            app.ModulationDropDown.Value = 'BPSK';
            app.ChannelSNRdBLabel = uilabel(app.SimulationControlsPanel);
            app.ChannelSNRdBLabel.Text = 'Channel SNR(dB)';
            app.SNRSlider = uislider(app.SimulationControlsPanel);
            app.SNRSlider.Limits = [0 20];
            app.SNRSlider.MajorTicks = [0 5 10 15 20];
            app.SNRSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20];
            app.SNRSlider.Value = 10;
            app.RunSimulationButton = uibutton(app.SimulationControlsPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResetButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export Results';
            app.PerformanceMetricsPanel = uipanel(app.GridLayout);
            app.PerformanceMetricsPanel.Title = 'Performance Metrics';
            app.BERLabel = uilabel(app.PerformanceMetricsPanel);
            app.BERLabel.Text = 'BER:';
            app.BERValueLabel = uilabel(app.PerformanceMetricsPanel);
            app.BERValueLabel.Text = 'Not run';
            app.ReconstructionMSELabel = uilabel(app.PerformanceMetricsPanel);
            app.ReconstructionMSELabel.Text = 'Reconstruction MSE:';
            app.MSEValueLabel = uilabel(app.PerformanceMetricsPanel);
            app.MSEValueLabel.Text = 'Not run';
            app.ShannonCapacityLabel = uilabel(app.PerformanceMetricsPanel);
            app.ShannonCapacityLabel.Text = 'Shannon Capacity:';
            app.CapacityValueLabel = uilabel(app.PerformanceMetricsPanel);
            app.CapacityValueLabel.Text = 'Not run';
            app.SignalandPerformancePlotsPanel = uipanel(app.GridLayout);
            app.SignalandPerformancePlotsPanel.Title = 'Signal and Performance Plots';
            app.GridLayout2 = uigridlayout(app.SignalandPerformancePlotsPanel);
            app.OriginalAxes = uiaxes(app.GridLayout2);
            title(app.OriginalAxes, 'Original Message')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.ConstellationAxes = uiaxes(app.GridLayout2);
            title(app.ConstellationAxes, 'Received Constellation')
            xlabel(app.ConstellationAxes, 'X')
            ylabel(app.ConstellationAxes, 'Y')
            zlabel(app.ConstellationAxes, 'Z')
            app.ReconstructionAxes = uiaxes(app.GridLayout2);
            title(app.ReconstructionAxes, 'Signal Reconstruction')
            xlabel(app.ReconstructionAxes, 'X')
            ylabel(app.ReconstructionAxes, 'Y')
            zlabel(app.ReconstructionAxes, 'Z')
            app.PerformanceAxes = uiaxes(app.GridLayout2);
            title(app.PerformanceAxes, 'BER and MSE vs SNR')
            xlabel(app.PerformanceAxes, 'X')
            ylabel(app.PerformanceAxes, 'Y')
            zlabel(app.PerformanceAxes, 'Z')
            % Show the figure after all components are created
            app.SmartAudioCommunicationSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartAudioCommunicationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmartAudioCommunicationSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmartAudioCommunicationSimulatorUIFigure)
        end
    end
end